classdef groupM2_Question7_CalculatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        INTRODUCTIONTab                 matlab.ui.container.Tab
        HTML                            matlab.ui.control.HTML
        Image7                          matlab.ui.control.Image
        Image8                          matlab.ui.control.Image
        ISECTIONTab                     matlab.ui.container.Tab
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        t1EditFieldLabel                matlab.ui.control.Label
        t1EditField                     matlab.ui.control.NumericEditField
        t2EditFieldLabel                matlab.ui.control.Label
        t2EditField                     matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.Button
        Areaofcrosssectionm2EditFieldLabel  matlab.ui.control.Label
        Areaofcrosssectionm2EditField   matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsLabel  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField  matlab.ui.control.NumericEditField
        NaturalfrequencyHzLabel         matlab.ui.control.Label
        NaturalfrequencyHzEditField     matlab.ui.control.NumericEditField
        PeriodofOscillationsEditFieldLabel  matlab.ui.control.Label
        PeriodofOscillationsEditField   matlab.ui.control.NumericEditField
        CriticalDampingNsmEditFieldLabel  matlab.ui.control.Label
        CriticalDampingNsmEditField     matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditFieldLabel  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditFieldLabel  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditFieldLabel  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditFieldLabel  matlab.ui.control.Label
        LogarithmicDecrementEditField   matlab.ui.control.NumericEditField
        QualityFactorEditFieldLabel     matlab.ui.control.Label
        QualityFactorEditField          matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditFieldLabel  matlab.ui.control.Label
        AreaMomentofInertiam4EditField  matlab.ui.control.NumericEditField
        StiffnessNmLabel                matlab.ui.control.Label
        StiffnessNmEditField            matlab.ui.control.NumericEditField
        DropDown_2                      matlab.ui.control.DropDown
        DropDown_3                      matlab.ui.control.DropDown
        BreadthEditField_2Label         matlab.ui.control.Label
        BreadthEditField_2              matlab.ui.control.NumericEditField
        DropDown_4                      matlab.ui.control.DropDown
        DropDown_5                      matlab.ui.control.DropDown
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        RECTANGULARSECTIONTab           matlab.ui.container.Tab
        DropDown_7                      matlab.ui.control.DropDown
        DropDown_8                      matlab.ui.control.DropDown
        HeightEditField_2Label          matlab.ui.control.Label
        HeightEditField_2               matlab.ui.control.NumericEditField
        BreadthEditField_3Label         matlab.ui.control.Label
        BreadthEditField_3              matlab.ui.control.NumericEditField
        CalculateButton_2               matlab.ui.control.Button
        Areaofcrosssectionm2EditField_2Label  matlab.ui.control.Label
        Areaofcrosssectionm2EditField_2  matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditField_2Label  matlab.ui.control.Label
        AreaMomentofInertiam4EditField_2  matlab.ui.control.NumericEditField
        StiffnessNmEditField_7Label     matlab.ui.control.Label
        StiffnessNmEditField_7          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_2Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_2  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_2Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_2   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_2Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_2  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_2Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_2   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_2Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_2  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_2Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_2  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_6Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_6  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_10Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_10  matlab.ui.control.NumericEditField
        QualityFactorEditField_4Label   matlab.ui.control.Label
        QualityFactorEditField_4        matlab.ui.control.NumericEditField
        Image2                          matlab.ui.control.Image
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes2_2                       matlab.ui.control.UIAxes
        CIRCULARSECTIONTab              matlab.ui.container.Tab
        CalculateButton_3               matlab.ui.control.Button
        Areaofcrosssectionm2EditField_3Label  matlab.ui.control.Label
        Areaofcrosssectionm2EditField_3  matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditField_3Label  matlab.ui.control.Label
        AreaMomentofInertiam4EditField_3  matlab.ui.control.NumericEditField
        StiffnessNmEditField_6Label     matlab.ui.control.Label
        StiffnessNmEditField_6          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_3Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_3  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_3Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_3   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_3Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_3  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_3Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_3   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_3Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_3  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_3Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_3  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_5Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_5  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_11Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_11  matlab.ui.control.NumericEditField
        QualityFactorEditField_5Label   matlab.ui.control.Label
        QualityFactorEditField_5        matlab.ui.control.NumericEditField
        DropDown_9                      matlab.ui.control.DropDown
        RadiusEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.NumericEditField
        Image3                          matlab.ui.control.Image
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes2_3                       matlab.ui.control.UIAxes
        TSECTIONTab                     matlab.ui.container.Tab
        CalculateButton_4               matlab.ui.control.Button
        Areaofcrosssectionm2EditField_4Label  matlab.ui.control.Label
        Areaofcrosssectionm2EditField_4  matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditField_4Label  matlab.ui.control.Label
        AreaMomentofInertiam4EditField_4  matlab.ui.control.NumericEditField
        StiffnessNmEditField_5Label     matlab.ui.control.Label
        StiffnessNmEditField_5          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_4Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_4  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_4Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_4   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_4Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_4  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_4Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_4   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_4Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_4  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_4Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_4  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_4Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_4  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_6Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_6  matlab.ui.control.NumericEditField
        QualityFactorEditField_6Label   matlab.ui.control.Label
        QualityFactorEditField_6        matlab.ui.control.NumericEditField
        DropDown_10                     matlab.ui.control.DropDown
        DropDown_11                     matlab.ui.control.DropDown
        DropDown_12                     matlab.ui.control.DropDown
        DropDown_13                     matlab.ui.control.DropDown
        HeightEditField_3Label          matlab.ui.control.Label
        HeightEditField_3               matlab.ui.control.NumericEditField
        t1EditField_2Label              matlab.ui.control.Label
        t1EditField_2                   matlab.ui.control.NumericEditField
        BreadthEditField_4Label         matlab.ui.control.Label
        BreadthEditField_4              matlab.ui.control.NumericEditField
        t2EditField_2Label              matlab.ui.control.Label
        t2EditField_2                   matlab.ui.control.NumericEditField
        Image4                          matlab.ui.control.Image
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes2_4                       matlab.ui.control.UIAxes
        CSECTIONTab                     matlab.ui.container.Tab
        CalculateButton_5               matlab.ui.control.Button
        Areaofcrosssectionm2EditField_5Label  matlab.ui.control.Label
        Areaofcrosssectionm2EditField_5  matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditField_5Label  matlab.ui.control.Label
        AreaMomentofInertiam4EditField_5  matlab.ui.control.NumericEditField
        StiffnessNmEditField_4Label     matlab.ui.control.Label
        StiffnessNmEditField_4          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_5Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_5  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_5Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_5   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_5Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_5  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_5Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_5   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_5Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_5  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_5Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_5  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_7Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_7  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_7Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_7  matlab.ui.control.NumericEditField
        QualityFactorEditField_7Label   matlab.ui.control.Label
        QualityFactorEditField_7        matlab.ui.control.NumericEditField
        DropDown_14                     matlab.ui.control.DropDown
        DropDown_15                     matlab.ui.control.DropDown
        DropDown_16                     matlab.ui.control.DropDown
        DropDown_17                     matlab.ui.control.DropDown
        HeightEditField_4Label          matlab.ui.control.Label
        HeightEditField_4               matlab.ui.control.NumericEditField
        t1EditField_3Label              matlab.ui.control.Label
        t1EditField_3                   matlab.ui.control.NumericEditField
        BreadthEditField_5Label         matlab.ui.control.Label
        BreadthEditField_5              matlab.ui.control.NumericEditField
        t2EditField_3Label              matlab.ui.control.Label
        t2EditField_3                   matlab.ui.control.NumericEditField
        Image5                          matlab.ui.control.Image
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes2_5                       matlab.ui.control.UIAxes
        LSECTIONTab                     matlab.ui.container.Tab
        Areaofcrosssectionm2EditField_6Label  matlab.ui.control.Label
        Areaofcrosssectionm2EditField_6  matlab.ui.control.NumericEditField
        AreaMomentofInertiam4EditField_6Label  matlab.ui.control.Label
        AreaMomentofInertiam4EditField_6  matlab.ui.control.NumericEditField
        StiffnessNmEditField_3Label     matlab.ui.control.Label
        StiffnessNmEditField_3          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_6Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_6  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_6Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_6   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_6Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_6  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_6Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_6   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_6Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_6  matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_6Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_6  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_8Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_8  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_8Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_8  matlab.ui.control.NumericEditField
        QualityFactorEditField_8Label   matlab.ui.control.Label
        QualityFactorEditField_8        matlab.ui.control.NumericEditField
        CalculateButton_6               matlab.ui.control.Button
        DropDown_18                     matlab.ui.control.DropDown
        DropDown_19                     matlab.ui.control.DropDown
        DropDown_20                     matlab.ui.control.DropDown
        DropDown_21                     matlab.ui.control.DropDown
        HeightEditField_5Label          matlab.ui.control.Label
        HeightEditField_5               matlab.ui.control.NumericEditField
        t1EditField_4Label              matlab.ui.control.Label
        t1EditField_4                   matlab.ui.control.NumericEditField
        BreadthEditField_6Label         matlab.ui.control.Label
        BreadthEditField_6              matlab.ui.control.NumericEditField
        t2EditField_4Label              matlab.ui.control.Label
        t2EditField_4                   matlab.ui.control.NumericEditField
        Image6                          matlab.ui.control.Image
        UIAxes_11                       matlab.ui.control.UIAxes
        UIAxes2_6                       matlab.ui.control.UIAxes
        OTHERSECTIONTab                 matlab.ui.container.Tab
        CalculateButton_7               matlab.ui.control.Button
        AreaofcrosssectionEditField_7Label  matlab.ui.control.Label
        AreaofcrosssectionEditField_7   matlab.ui.control.NumericEditField
        AreaMomentofInertiaEditField_7Label  matlab.ui.control.Label
        AreaMomentofInertiaEditField_7  matlab.ui.control.NumericEditField
        StiffnessNmEditField_2Label     matlab.ui.control.Label
        StiffnessNmEditField_2          matlab.ui.control.NumericEditField
        CircularNaturalfrequencyradsEditField_7Label  matlab.ui.control.Label
        CircularNaturalfrequencyradsEditField_7  matlab.ui.control.NumericEditField
        NaturalfrequencyHzEditField_7Label  matlab.ui.control.Label
        NaturalfrequencyHzEditField_7   matlab.ui.control.NumericEditField
        PeriodofOscillationsEditField_7Label  matlab.ui.control.Label
        PeriodofOscillationsEditField_7  matlab.ui.control.NumericEditField
        CriticalDampingNsmEditField_7Label  matlab.ui.control.Label
        CriticalDampingNsmEditField_7   matlab.ui.control.NumericEditField
        ViscousdampingcoefficientNsmEditField_7Label  matlab.ui.control.Label
        ViscousdampingcoefficientNsmEditField_7  matlab.ui.control.NumericEditField
        DampedNaturalFrequencyHzEditField_9Label  matlab.ui.control.Label
        DampedNaturalFrequencyHzEditField_9  matlab.ui.control.NumericEditField
        LogarithmicDecrementEditField_9Label  matlab.ui.control.Label
        LogarithmicDecrementEditField_9  matlab.ui.control.NumericEditField
        QualityFactorEditField_9Label   matlab.ui.control.Label
        QualityFactorEditField_9        matlab.ui.control.NumericEditField
        DampedNaturalAngularfrequencyradsEditField_7Label  matlab.ui.control.Label
        DampedNaturalAngularfrequencyradsEditField_7  matlab.ui.control.NumericEditField
        DropDown_22                     matlab.ui.control.DropDown
        DropDown_23                     matlab.ui.control.DropDown
        UIAxes_13                       matlab.ui.control.UIAxes
        UIAxes2_7                       matlab.ui.control.UIAxes
        FORMULAUSEDTab                  matlab.ui.container.Tab
        Image9                          matlab.ui.control.Image
        Image10                         matlab.ui.control.Image
        Image11                         matlab.ui.control.Image
        MaterialLabel                   matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        LengthLLabel                    matlab.ui.control.Label
        LengthLEditField                matlab.ui.control.NumericEditField
        DampingRatioLabel               matlab.ui.control.Label
        DampingRatioEditField           matlab.ui.control.NumericEditField
        CentralMassLabel                matlab.ui.control.Label
        CentralMassEditField            matlab.ui.control.NumericEditField
        DropDown                        matlab.ui.control.DropDown
        DropDown_6                      matlab.ui.control.DropDown
        InitialdisplacementmLabel       matlab.ui.control.Label
        InitialdisplacementmEditField   matlab.ui.control.NumericEditField
        InitialvelocitymsLabel          matlab.ui.control.Label
        InitialvelocitymsEditField      matlab.ui.control.NumericEditField
        FIXEDFIXEDBEAMCALCULATORLabel   matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            %I section

            b=app.BreadthEditField_2.Value; 
            h=app.HeightEditField.Value;
            t1=app.t1EditField.Value;
            t2=app.t2EditField.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0= app.InitialdisplacementmEditField.Value;
            v0=app.InitialvelocitymsEditField.Value;

            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % Breadth conversion 2
            if(app.DropDown_2.Value=="mm")
                b=0.001*b;

            elseif(app.DropDown_2.Value=="m")
                b=1*b;

            elseif(app.DropDown_2.Value=="ft")
                b=0.3048*b;

            elseif(app.DropDown_2.Value=="cm")
                b=0.01*b;

            end

            % Height conversion 3
            if(app.DropDown_4.Value=="mm")
                h=0.001*h;

            elseif(app.DropDown_4.Value=="m")
                h=1*h;

            elseif(app.DropDown_4.Value=="ft")
                h=0.3048*h;

            elseif(app.DropDown_4.Value=="cm")
                h=0.01*h;

            end

            % t1 conversion 4
            if(app.DropDown_3.Value=="mm")
                t1=0.001*t1;

            elseif(app.DropDown_3.Value=="m")
                t1=1*t1;

            elseif(app.DropDown_3.Value=="ft")
                t1=0.3048*t1;

            elseif(app.DropDown_3.Value=="cm")
                t1=0.01*t1;

            end

            % t2 conversion 5
            if(app.DropDown_5.Value=="mm")
                t2=0.001*t2;

            elseif(app.DropDown_5.Value=="m")
                t2=1*t2;

            elseif(app.DropDown_5.Value=="ft")
                t2=0.3048*t2;

            elseif(app.DropDown_5.Value=="cm")
                t2=0.01*t2;

            end

            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section for i section

            area=(2 * b * t2) + ((h - (2*t2))*t1);
            app.Areaofcrosssectionm2EditField.Value=area;

            %area moment of inertia

            I= (b * (h^3) /12) - ((b - t1) * ((h - (2 * t2) )^3))/12;
            app.AreaMomentofInertiam4EditField.Value=I;

            volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField.Value=c;
            
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField.Value=Q;
            
            %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField.Value=LD;
                 
            catch ME
                 
                
            end
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.0001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end
 
            plot(app.UIAxes,t3,x2)
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2,x,y,'|','MarkerSize',25);
                app.UIAxes2.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end
        end

        % Callback function
        function MaterialDropDownValueChanged(app, event)


        end

        % Value changed function: MaterialDropDown
        function MaterialDropDownValueChanged2(app, event)


        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            b=app.BreadthEditField_3.Value;
            h=app.HeightEditField_2.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value;
            v0 = app.InitialvelocitymsEditField.Value;


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % Breadth conversion 2
            if(app.DropDown_7.Value=="mm")
                b=0.001*b;

            elseif(app.DropDown_7.Value=="m")
                b=1*b;

            elseif(app.DropDown_7.Value=="ft")
                b=0.3048*b;

            elseif(app.DropDown_7.Value=="cm")
                b=0.01*b;

            end

            % Height conversion 8
            if(app.DropDown_8.Value=="mm")
                h=0.001*h;

            elseif(app.DropDown_8.Value=="m")
                h=1*h;

            elseif(app.DropDown_8.Value=="ft")
                h=0.3048*h;

            elseif(app.DropDown_8.Value=="cm")
                h=0.01*h;

            end

            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section

            area=b*h;
            app.Areaofcrosssectionm2EditField_2.Value=area;

            %area moment of inertia

            I= (b * (h^3) /12);
            app.AreaMomentofInertiam4EditField_2.Value=I;

           
            volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_7.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_2.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_2.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_2.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_2.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_2.Value=c;
            
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_4.Value=Q;
            

             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_2.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_6.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_10.Value=LD;
                 
            catch ME
                 
                
            end
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end

            
            plot(app.UIAxes_3,t3,x2)
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_2,x,y,'|','MarkerSize',25);
                app.UIAxes2_2.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end


        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            r=app.RadiusEditField.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value
            v0 = app.InitialvelocitymsEditField.Value


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % radius conversion 2
            if(app.DropDown_9.Value=="mm")
                r=0.001*r;

            elseif(app.DropDown_9.Value=="m")
                r=1*r;

            elseif(app.DropDown_9.Value=="ft")
                r=0.3048*r;

            elseif(app.DropDown_9.Value=="cm")
                r=0.01*r;

            end


            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section

            area=pi*r^2;
            app.Areaofcrosssectionm2EditField_3.Value=area;

            %area moment of inertia

            I= pi*(r^4)/4;
            app.AreaMomentofInertiam4EditField_3.Value=I;

            volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_6.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_3.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_3.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_3.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_3.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_3.Value=c;
           
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_5.Value=Q;


             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_3.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_5.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_11.Value=LD;
                 
            catch ME
                 
                
            end
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end

            
            plot(app.UIAxes_5,t3,x2)
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_3,x,y,'|','MarkerSize',25);
                app.UIAxes2_3.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end


        end

        % Button pushed function: CalculateButton_4
        function CalculateButton_4Pushed(app, event)
            b=app.BreadthEditField_4.Value;
            h=app.HeightEditField_3.Value;
            t1=app.t1EditField_2.Value;
            t2=app.t2EditField_2.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value
            v0 = app.InitialvelocitymsEditField.Value


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % Breadth conversion 2
            if(app.DropDown_10.Value=="mm")
                b=0.001*b;

            elseif(app.DropDown_10.Value=="m")
                b=1*b;

            elseif(app.DropDown_10.Value=="ft")
                b=0.3048*b;

            elseif(app.DropDown_10.Value=="cm")
                b=0.01*b;

            end

            % Height conversion 3
            if(app.DropDown_12.Value=="mm")
                h=0.001*h;

            elseif(app.DropDown_12.Value=="m")
                h=1*h;

            elseif(app.DropDown_12.Value=="ft")
                h=0.3048*h;

            elseif(app.DropDown_12.Value=="cm")
                h=0.01*h;

            end

            % t1 conversion 4
            if(app.DropDown_11.Value=="mm")
                t1=0.001*t1;

            elseif(app.DropDown_11.Value=="m")
                t1=1*t1;

            elseif(app.DropDown_11.Value=="ft")
                t1=0.3048*t1;

            elseif(app.DropDown_11.Value=="cm")
                t1=0.01*t1;

            end

            % t2 conversion 5
            if(app.DropDown_13.Value=="mm")
                t2=0.001*t2;

            elseif(app.DropDown_13.Value=="m")
                t2=1*t2;

            elseif(app.DropDown_13.Value=="ft")
                t2=0.3048*t2;

            elseif(app.DropDown_13.Value=="cm")
                t2=0.01*t2;

            end

            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section

            area=(b * t2) + ((h - t2)*t1);
            app.Areaofcrosssectionm2EditField_4.Value=area;

            %area moment of inertia

            e2=0.5*(t1*h^2 +(b-t1)*t2^2)/(t1*h+(b-t1)*t2);

            I= (((t1 * (h^3))+((b-t1)*(t2^3)))/3) - (((t1*h) + ((b-t1)*t2))*(e2^2));
            app.AreaMomentofInertiam4EditField_4.Value=I;

            volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_5.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_4.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_4.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_4.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_4.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_4.Value=c;
            
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_6.Value=Q;
           

             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_4.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_4.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_6.Value=LD;
                 
            catch ME
                 
                
            end
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end
            
  
            plot(app.UIAxes_7,t3,x2)


            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_4,x,y,'|','MarkerSize',25);
                app.UIAxes2_4.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end

        end

        % Button pushed function: CalculateButton_5
        function CalculateButton_5Pushed(app, event)
            b=app.BreadthEditField_5.Value;
            h=app.HeightEditField_4.Value;
            t1=app.t1EditField_3.Value;
            t2=app.t2EditField_3.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value
            v0 = app.InitialvelocitymsEditField.Value


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % Breadth conversion 2
            if(app.DropDown_14.Value=="mm")
                b=0.001*b;

            elseif(app.DropDown_14.Value=="m")
                b=1*b;

            elseif(app.DropDown_14.Value=="ft")
                b=0.3048*b;

            elseif(app.DropDown_14.Value=="cm")
                b=0.01*b;

            end

            % Height conversion 3
            if(app.DropDown_16.Value=="mm")
                h=0.001*h;

            elseif(app.DropDown_16.Value=="m")
                h=1*h;

            elseif(app.DropDown_16.Value=="ft")
                h=0.3048*h;

            elseif(app.DropDown_16.Value=="cm")
                h=0.01*h;

            end

            % t1 conversion 4
            if(app.DropDown_15.Value=="mm")
                t1=0.001*t1;

            elseif(app.DropDown_15.Value=="m")
                t1=1*t1;

            elseif(app.DropDown_15.Value=="ft")
                t1=0.3048*t1;

            elseif(app.DropDown_15.Value=="cm")
                t1=0.01*t1;

            end

            % t2 conversion 5
            if(app.DropDown_17.Value=="mm")
                t2=0.001*t2;

            elseif(app.DropDown_17.Value=="m")
                t2=1*t2;

            elseif(app.DropDown_17.Value=="ft")
                t2=0.3048*t2;

            elseif(app.DropDown_17.Value=="cm")
                t2=0.01*t2;

            end

            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section

            area=(2 * b * t2) + ((h - (2*t2))*t1);
            app.Areaofcrosssectionm2EditField_5.Value=area;

            %area moment of inertia

            I= (b * (h^3) /12) - ((b - t1) * ((h - (2 * t2) )^3))/12;
            app.AreaMomentofInertiam4EditField_5.Value=I;

           volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_4.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_5.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_5.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_5.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_5.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_5.Value=c;
            
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_7.Value=Q;
            

             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_5.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_7.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_7.Value=LD;
                 
            catch ME
                 
                
            end
            
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end
         
            plot(app.UIAxes_9,t3,x2)
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_5,x,y,'|','MarkerSize',25);
                app.UIAxes2_5.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end


        end

        % Button pushed function: CalculateButton_6
        function CalculateButton_6Pushed(app, event)
            b=app.BreadthEditField_6.Value;
            h=app.HeightEditField_5.Value;
            t1=app.t1EditField_4.Value;
            t2=app.t2EditField_4.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value
            v0 = app.InitialvelocitymsEditField.Value


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end

            % Breadth conversion 2
            if(app.DropDown_18.Value=="mm")
                b=0.001*b;

            elseif(app.DropDown_18.Value=="m")
                b=1*b;

            elseif(app.DropDown_18.Value=="ft")
                b=0.3048*b;

            elseif(app.DropDown_18.Value=="cm")
                b=0.01*b;

            end

            % Height conversion 3
            if(app.DropDown_20.Value=="mm")
                h=0.001*h;

            elseif(app.DropDown_20.Value=="m")
                h=1*h;

            elseif(app.DropDown_20.Value=="ft")
                h=0.3048*h;

            elseif(app.DropDown_20.Value=="cm")
                h=0.01*h;

            end

            % t1 conversion 4
            if(app.DropDown_19.Value=="mm")
                t1=0.001*t1;

            elseif(app.DropDown_19.Value=="m")
                t1=1*t1;

            elseif(app.DropDown_19.Value=="ft")
                t1=0.3048*t1;

            elseif(app.DropDown_19.Value=="cm")
                t1=0.01*t1;

            end

            % t2 conversion 5
            if(app.DropDown_21.Value=="mm")
                t2=0.001*t2;

            elseif(app.DropDown_21.Value=="m")
                t2=1*t2;

            elseif(app.DropDown_21.Value=="ft")
                t2=0.3048*t2;

            elseif(app.DropDown_21.Value=="cm")
                t2=0.01*t2;

            end

            % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end

            %Area of cross section

            area=( b * t2) + ((h - t2)*t1);
            app.Areaofcrosssectionm2EditField_6.Value=area;

            %area moment of inertia

            e2=0.5*(t1*h^2 +(b-t1)*t2^2)/(t1*h+(b-t1)*t2);

            I= (((t1 * (h^3))+((b-t1)*(t2^3)))/3) - (((t1*h) + ((b-t1)*t2))*(e2^2));
            app.AreaMomentofInertiam4EditField_6.Value=I;

           volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_3.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_6.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_6.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_6.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_6.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_6.Value=c;
           
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_8.Value=Q;
           

             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_6.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_8.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_8.Value=LD;
                 
            catch ME
                 
                
            end
            
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end
    
            
            plot(app.UIAxes_11,t3,x2)
            
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_6,x,y,'|','MarkerSize',25);
                app.UIAxes2_6.YLim = [-(max(x3)) (max(x3))];
                
                pause(0.001);
       
            end

        end

        % Button pushed function: CalculateButton_7
        function CalculateButton_7Pushed(app, event)
            area=app.AreaofcrosssectionEditField_7.Value;
            I=app.AreaMomentofInertiaEditField_7.Value;
            l=app.LengthLEditField.Value;
            cmass=app.CentralMassEditField.Value;
            zeta=app.DampingRatioEditField.Value;
            E=0;
            density=0;
            
            %% initial disp and velocity
            x0 = app.InitialdisplacementmEditField.Value
            v0 = app.InitialvelocitymsEditField.Value


            % Material selection block
            if(app.MaterialDropDown.Value=="Steel")
                E=200 * (10^9);%Pascal
                density=7750;%kg/m3


            elseif(app.MaterialDropDown.Value=="Aluminium")
                E=70.33*(10^9);
                density=2712;


            elseif(app.MaterialDropDown.Value=="Bronze")
                E=111.006*(10^9);
                density=8304;
            end

            % Length conversion 1
            if(app.DropDown.Value=="mm")
                l=0.001*l;

            elseif(app.DropDown.Value=="m")
                l=1*l;

            elseif(app.DropDown.Value=="ft")
                l=0.3048*l;

            elseif(app.DropDown.Value=="cm")
                l=0.01*l;

            end
             % m conversion 5
            if(app.DropDown_6.Value=="kg")
                cmass=1*cmass;

            elseif(app.DropDown_6.Value=="lb")
                cmass=0.453592*cmass;

            elseif(app.DropDown_6.Value=="gm")
                cmass=0.001*cmass;

            end
            
            % area conversion 5
            if(app.DropDown_22.Value=="mm^2")
               area=area*0.000001;

            elseif(app.DropDown_22.Value=="cm^2")
                area=area*0.0001;

            end
            
             % volume conversion 5
            if(app.DropDown_23.Value=="mm^4")
               I=I*0.000000001;

            elseif(app.DropDown_23.Value=="cm^4")
                I=I*0.000001;

            end
            
            volume= area * l;

            %Mass of rod
            mass1= volume * density;

            %Lumped Mass
            mass2=(13/35)*mass1;

            %Total mass
            tmass = mass2 + cmass;

            %Stiffness
            k=(192 * E * I )/(l^3);
            app.StiffnessNmEditField_2.Value=k;


            %%Value of pi
            p=pi;

            %%Natural Circular frequency
            wn=sqrt(k/tmass);
            app.CircularNaturalfrequencyradsEditField_7.Value=wn;
            %%Natural Circular frequency
            fn=wn /(2 * p) ;
            app.NaturalfrequencyHzEditField_7.Value=fn;
            %%Time period
            t=1/fn;
            app.PeriodofOscillationsEditField_7.Value=t;
            %%Critcial Damping
            cc=2*tmass*wn;
            app.CriticalDampingNsmEditField_7.Value=cc;
            %%Damping Factor
            c=zeta*cc;
            app.ViscousdampingcoefficientNsmEditField_7.Value=c;
            
            %%Quality factor
            Q=1/(2 * zeta);
            app.QualityFactorEditField_9.Value=Q;
           
            
             %%Damped Natural angular Frequency
            try
                wd= wn * ( sqrt(1-(zeta^2)) );
                app.DampedNaturalAngularfrequencyradsEditField_7.Value=wd;
                %%Damped Natural Frequency
                fd=wd /(2 * p);
                 app.DampedNaturalFrequencyHzEditField_9.Value=fd;
            catch ME
 
            end


            %Logarithmic decrement
            try
                LD= (2 * p)/(sqrt((1/(zeta^2))-1));
                 app.LogarithmicDecrementEditField_9.Value=LD;
                 
            catch ME
                 
                
            end
            
            x2=[x0,x0];
            t3=[0,0];
            count=0;
            
            if zeta<1
                for t1=0:0.00001:1000000
                    t2=t1;
                    expvar=exp(-zeta*wn*t2);
                    cosvar=x0*cos(wd*t2);
                    v0var=(v0+(zeta*wn*x0))/wd;
                    sinvar=sin(wd*t2);
                    x1=expvar*(cosvar+(v0var*sinvar));
                    x2(end+1)=x1;
                    t3(end+1)=t2;
                    if (x2(end-2)>x1) && (x2(end-2)>x2(end-3))
                       count=1+count;
                    end
                    if count ==10
                       break;
                    end 
                    
                end
            end


            if zeta==1
                for t1=0:0.0001:100000
                    x1=(exp( -wn * t1))* (x0+((v0 * t1)+(wn * x0 * t1)));
                    x2(end+1)=x1;
                    t3(end+1)=t1;
                    if x1<(x0/1000)
                        break
                    end
                end
            end
            
            if zeta>1
               for t1=0:0.0001:100000
                    zeta1=zeta+((zeta^2-1))^0.5;
                    zeta2=exp((-zeta+((zeta^2-1))^0.5)*wn*t1);
                    zeta3=zeta-((zeta^2-1))^0.5;
                    zeta4=exp((-zeta-((zeta^2-1))^0.5)*wn*t1);
                    term1=((x0*wn*zeta1)+v0)*zeta2/(2*wn*(zeta^2-1)^0.5);
                    term2=((-x0*wn*zeta3)-v0)*zeta4/(2*wn*(zeta^2-1)^0.5);
                    x1=term1+term2;
                    x2(end+1)=x1;
                    t3(end+1)=t1; 
                    if x1<(x0/1000)
                        break
                    end
               end
            end
 
            
            plot(app.UIAxes_13,t3,x2)
            
            
            % beam damping graph
            x3=[];
            incre=int64(length(x2)/50);
            
            for j=1:incre:length(x2)
                x3(end+1)=x2(j);
            end
       
            x3(end+1)=0;
            
            x=-(pi*0.5):0.005:(pi*0.5);
            for i=1:length(x3)
                y=cos(x)*x3(i);
                
                plot(app.UIAxes2_7,x,y,'|','MarkerSize',25);
                app.UIAxes2_7.YLim = [-(max(x3)) (max(x3))];
               
                pause(0.001);
       
            end

        end

        % Value changed function: DampingRatioEditField
        function DampingRatioEditFieldValueChanged(app, event)
            value = app.DampingRatioEditField.Value;
            
        end

        % Callback function
        function ButtonPushed(app, event)
            x=-(pi*0.5):0.001:(pi*0.5);
            for z=-0.01:0.0001:0.01
                clf;
                y=cos(x)*sin(z);
                plot(app.UIAxes2,x,y);
                pause(0.0000001);
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.INTRODUCTIONTab = uitab(app.TabGroup);
            app.INTRODUCTIONTab.Title = 'INTRODUCTION';
            app.HTML = uihtml(app.INTRODUCTIONTab);
            app.HTML.HTMLSource = '{''<!DOCTYPE html><html><body><p><h1><center>Free vibration of fixed fixed beam</center></h1></p><p>A beam that is fixed at both ends is called a fixed fixed beam. Fixed beams are not allowed the vertical movement or rotation of the beam. In this beam, no bending moment will produce. Fixed beams are only under the shear force and are generally used in the trusses and like other structures. Both ends of the beam rigidly fixed with supports.<br>k, the stiffness of the system is a property which depends on the length (l), moment of inertia (I) and Young''s Modulus (E) of the material of the beam and for a fixed fixed beam is given by:</p><p><center>. </center></p><p><center>. </center></p><p>This Fixed Fixed beam calculator will calculate mass-spring-damper natural frequency, circular frequency, damping factor, Q factor, critical damping, damped natural frequency <br>It also plots the Amplitude v/s time graph for the inputs given and outputs animation of vibration of the beam</body></html><p><h3>Instructions:</h3></p>1.Input the material and system properties<br>2.Input the loading conditions<br>3.Select cross-section type<br>4.Input the required dimensions<br>5.Analyse the results displayed<br>''}';
            app.Image7 = uiimage(app.INTRODUCTIONTab);
            app.Image7.ImageSource = 'fixed fixed.png';
            app.Image8 = uiimage(app.INTRODUCTIONTab);
            app.Image8.ImageSource = 'kvalue.PNG';
            app.ISECTIONTab = uitab(app.TabGroup);
            app.ISECTIONTab.Title = 'I SECTION';
            app.HeightEditFieldLabel = uilabel(app.ISECTIONTab);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.HeightEditField.Limits = [0 Inf];
            app.HeightEditField.Value = 1;
            app.t1EditFieldLabel = uilabel(app.ISECTIONTab);
            app.t1EditFieldLabel.Text = 't1';
            app.t1EditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.t1EditField.Limits = [0 Inf];
            app.t1EditField.Value = 1;
            app.t2EditFieldLabel = uilabel(app.ISECTIONTab);
            app.t2EditFieldLabel.Text = 't2';
            app.t2EditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.t2EditField.Limits = [0 Inf];
            app.t2EditField.Value = 1;
            app.CalculateButton = uibutton(app.ISECTIONTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Areaofcrosssectionm2EditFieldLabel = uilabel(app.ISECTIONTab);
            app.Areaofcrosssectionm2EditFieldLabel.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsLabel = uilabel(app.ISECTIONTab);
            app.CircularNaturalfrequencyradsLabel.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.NaturalfrequencyHzLabel = uilabel(app.ISECTIONTab);
            app.NaturalfrequencyHzLabel.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.PeriodofOscillationsEditFieldLabel = uilabel(app.ISECTIONTab);
            app.PeriodofOscillationsEditFieldLabel.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.CriticalDampingNsmEditFieldLabel = uilabel(app.ISECTIONTab);
            app.CriticalDampingNsmEditFieldLabel.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditFieldLabel = uilabel(app.ISECTIONTab);
            app.ViscousdampingcoefficientNsmEditFieldLabel.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditFieldLabel = uilabel(app.ISECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditFieldLabel.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditFieldLabel = uilabel(app.ISECTIONTab);
            app.DampedNaturalFrequencyHzEditFieldLabel.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.LogarithmicDecrementEditFieldLabel = uilabel(app.ISECTIONTab);
            app.LogarithmicDecrementEditFieldLabel.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.QualityFactorEditFieldLabel = uilabel(app.ISECTIONTab);
            app.QualityFactorEditFieldLabel.Text = 'Quality Factor';
            app.QualityFactorEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditFieldLabel = uilabel(app.ISECTIONTab);
            app.AreaMomentofInertiam4EditFieldLabel.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.StiffnessNmLabel = uilabel(app.ISECTIONTab);
            app.StiffnessNmLabel.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField = uieditfield(app.ISECTIONTab, 'numeric');
            app.DropDown_2 = uidropdown(app.ISECTIONTab);
            app.DropDown_2.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_2.Value = 'cm';
            app.DropDown_3 = uidropdown(app.ISECTIONTab);
            app.DropDown_3.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_3.Value = 'mm';
            app.BreadthEditField_2Label = uilabel(app.ISECTIONTab);
            app.BreadthEditField_2Label.Text = 'Breadth';
            app.BreadthEditField_2 = uieditfield(app.ISECTIONTab, 'numeric');
            app.BreadthEditField_2.Limits = [0 Inf];
            app.BreadthEditField_2.Value = 1;
            app.DropDown_4 = uidropdown(app.ISECTIONTab);
            app.DropDown_4.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_4.Value = 'cm';
            app.DropDown_5 = uidropdown(app.ISECTIONTab);
            app.DropDown_5.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_5.Value = 'mm';
            app.Image = uiimage(app.ISECTIONTab);
            app.Image.ImageSource = 'i section.PNG';
            app.UIAxes = uiaxes(app.ISECTIONTab);
            title(app.UIAxes, {'Amplitude (m) vs Time (s) Graph'; ''})
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.ISECTIONTab);
            title(app.UIAxes2, {'Fixed Fixed beam free vibration'; ''})
            xlabel(app.UIAxes2, {''; ''})
            ylabel(app.UIAxes2, 'Displacement (m)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2.XTickLabel = '';
            app.RECTANGULARSECTIONTab = uitab(app.TabGroup);
            app.RECTANGULARSECTIONTab.Title = 'RECTANGULAR SECTION ';
            app.DropDown_7 = uidropdown(app.RECTANGULARSECTIONTab);
            app.DropDown_7.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_7.Value = 'cm';
            app.DropDown_8 = uidropdown(app.RECTANGULARSECTIONTab);
            app.DropDown_8.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_8.Value = 'cm';
            app.HeightEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.HeightEditField_2Label.Text = 'Height';
            app.HeightEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.HeightEditField_2.Limits = [0 Inf];
            app.HeightEditField_2.Value = 1;
            app.BreadthEditField_3Label = uilabel(app.RECTANGULARSECTIONTab);
            app.BreadthEditField_3Label.Text = 'Breadth';
            app.BreadthEditField_3 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.BreadthEditField_3.Limits = [0 Inf];
            app.BreadthEditField_3.Value = 1;
            app.CalculateButton_2 = uibutton(app.RECTANGULARSECTIONTab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.Areaofcrosssectionm2EditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.Areaofcrosssectionm2EditField_2Label.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.AreaMomentofInertiam4EditField_2Label.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.StiffnessNmEditField_7Label = uilabel(app.RECTANGULARSECTIONTab);
            app.StiffnessNmEditField_7Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_7 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_2Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.NaturalfrequencyHzEditField_2Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.PeriodofOscillationsEditField_2Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.CriticalDampingNsmEditField_2Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_2Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_2Label = uilabel(app.RECTANGULARSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_2Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_2 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_6Label = uilabel(app.RECTANGULARSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_6Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_6 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_10Label = uilabel(app.RECTANGULARSECTIONTab);
            app.LogarithmicDecrementEditField_10Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_10 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.QualityFactorEditField_4Label = uilabel(app.RECTANGULARSECTIONTab);
            app.QualityFactorEditField_4Label.Text = 'Quality Factor';
            app.QualityFactorEditField_4 = uieditfield(app.RECTANGULARSECTIONTab, 'numeric');
            app.Image2 = uiimage(app.RECTANGULARSECTIONTab);
            app.Image2.ImageSource = 'rect section.PNG';
            app.UIAxes_3 = uiaxes(app.RECTANGULARSECTIONTab);
            title(app.UIAxes_3, {'Amplitude (m) vs Time (s) Graph'; ''})
            xlabel(app.UIAxes_3, 'Time (s) ')
            ylabel(app.UIAxes_3, 'Amplitude (m)')
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes2_2 = uiaxes(app.RECTANGULARSECTIONTab);
            title(app.UIAxes2_2, 'Fixed Fixed beam free vibration')
            xlabel(app.UIAxes2_2, {''; ''})
            ylabel(app.UIAxes2_2, 'Displacement (m)')
            zlabel(app.UIAxes2_2, 'Z')
            app.UIAxes2_2.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_2.XTickLabel = '';
            app.CIRCULARSECTIONTab = uitab(app.TabGroup);
            app.CIRCULARSECTIONTab.Title = 'CIRCULAR SECTION ';
            app.CalculateButton_3 = uibutton(app.CIRCULARSECTIONTab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.Areaofcrosssectionm2EditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.Areaofcrosssectionm2EditField_3Label.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.AreaMomentofInertiam4EditField_3Label.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.StiffnessNmEditField_6Label = uilabel(app.CIRCULARSECTIONTab);
            app.StiffnessNmEditField_6Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_6 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_3Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.NaturalfrequencyHzEditField_3Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.PeriodofOscillationsEditField_3Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.CriticalDampingNsmEditField_3Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_3Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_3Label = uilabel(app.CIRCULARSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_3Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_3 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_5Label = uilabel(app.CIRCULARSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_5Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_5 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_11Label = uilabel(app.CIRCULARSECTIONTab);
            app.LogarithmicDecrementEditField_11Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_11 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.QualityFactorEditField_5Label = uilabel(app.CIRCULARSECTIONTab);
            app.QualityFactorEditField_5Label.Text = 'Quality Factor';
            app.QualityFactorEditField_5 = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.DropDown_9 = uidropdown(app.CIRCULARSECTIONTab);
            app.DropDown_9.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_9.Value = 'cm';
            app.RadiusEditFieldLabel = uilabel(app.CIRCULARSECTIONTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.CIRCULARSECTIONTab, 'numeric');
            app.RadiusEditField.Limits = [0 Inf];
            app.RadiusEditField.Value = 1;
            app.Image3 = uiimage(app.CIRCULARSECTIONTab);
            app.Image3.ImageSource = 'circle section.png';
            app.UIAxes_5 = uiaxes(app.CIRCULARSECTIONTab);
            title(app.UIAxes_5, 'Amplitude (m) vs Time (s) Graph')
            xlabel(app.UIAxes_5, 'Time (s)')
            ylabel(app.UIAxes_5, 'Amplitude (m) ')
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes2_3 = uiaxes(app.CIRCULARSECTIONTab);
            title(app.UIAxes2_3, 'Fixed Fixed beam free vibration')
            xlabel(app.UIAxes2_3, {''; ''})
            ylabel(app.UIAxes2_3, 'Displacement (m)')
            zlabel(app.UIAxes2_3, 'Z')
            app.UIAxes2_3.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_3.XTickLabel = '';
            app.TSECTIONTab = uitab(app.TabGroup);
            app.TSECTIONTab.Title = 'T SECTION ';
            app.CalculateButton_4 = uibutton(app.TSECTIONTab, 'push');
            app.CalculateButton_4.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_4Pushed, true);
            app.CalculateButton_4.Text = 'Calculate';
            app.Areaofcrosssectionm2EditField_4Label = uilabel(app.TSECTIONTab);
            app.Areaofcrosssectionm2EditField_4Label.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditField_4Label = uilabel(app.TSECTIONTab);
            app.AreaMomentofInertiam4EditField_4Label.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.StiffnessNmEditField_5Label = uilabel(app.TSECTIONTab);
            app.StiffnessNmEditField_5Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_5 = uieditfield(app.TSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_4Label = uilabel(app.TSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_4Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_4Label = uilabel(app.TSECTIONTab);
            app.NaturalfrequencyHzEditField_4Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_4Label = uilabel(app.TSECTIONTab);
            app.PeriodofOscillationsEditField_4Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_4Label = uilabel(app.TSECTIONTab);
            app.CriticalDampingNsmEditField_4Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_4Label = uilabel(app.TSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_4Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_4Label = uilabel(app.TSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_4Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_4Label = uilabel(app.TSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_4Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_6Label = uilabel(app.TSECTIONTab);
            app.LogarithmicDecrementEditField_6Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_6 = uieditfield(app.TSECTIONTab, 'numeric');
            app.QualityFactorEditField_6Label = uilabel(app.TSECTIONTab);
            app.QualityFactorEditField_6Label.Text = 'Quality Factor';
            app.QualityFactorEditField_6 = uieditfield(app.TSECTIONTab, 'numeric');
            app.DropDown_10 = uidropdown(app.TSECTIONTab);
            app.DropDown_10.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_10.Value = 'cm';
            app.DropDown_11 = uidropdown(app.TSECTIONTab);
            app.DropDown_11.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_11.Value = 'mm';
            app.DropDown_12 = uidropdown(app.TSECTIONTab);
            app.DropDown_12.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_12.Value = 'cm';
            app.DropDown_13 = uidropdown(app.TSECTIONTab);
            app.DropDown_13.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_13.Value = 'mm';
            app.HeightEditField_3Label = uilabel(app.TSECTIONTab);
            app.HeightEditField_3Label.Text = 'Height';
            app.HeightEditField_3 = uieditfield(app.TSECTIONTab, 'numeric');
            app.HeightEditField_3.Limits = [0 Inf];
            app.HeightEditField_3.Value = 1;
            app.t1EditField_2Label = uilabel(app.TSECTIONTab);
            app.t1EditField_2Label.Text = 't1';
            app.t1EditField_2 = uieditfield(app.TSECTIONTab, 'numeric');
            app.t1EditField_2.Limits = [0 Inf];
            app.t1EditField_2.Value = 1;
            app.BreadthEditField_4Label = uilabel(app.TSECTIONTab);
            app.BreadthEditField_4Label.Text = 'Breadth';
            app.BreadthEditField_4 = uieditfield(app.TSECTIONTab, 'numeric');
            app.BreadthEditField_4.Limits = [0 Inf];
            app.BreadthEditField_4.Value = 1;
            app.t2EditField_2Label = uilabel(app.TSECTIONTab);
            app.t2EditField_2Label.Text = 't2';
            app.t2EditField_2 = uieditfield(app.TSECTIONTab, 'numeric');
            app.t2EditField_2.Limits = [0 Inf];
            app.t2EditField_2.Value = 1;
            app.Image4 = uiimage(app.TSECTIONTab);
            app.Image4.ImageSource = 't section.PNG';
            app.UIAxes_7 = uiaxes(app.TSECTIONTab);
            title(app.UIAxes_7, 'Amplitude (m) vs Time (s) Graph')
            xlabel(app.UIAxes_7, 'Time (s)')
            ylabel(app.UIAxes_7, 'Amplitude (m) ')
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes2_4 = uiaxes(app.TSECTIONTab);
            title(app.UIAxes2_4, {'Fixed Fixed beam free vibration'; ''})
            xlabel(app.UIAxes2_4, {''; ''})
            ylabel(app.UIAxes2_4, 'Displacement (m)')
            zlabel(app.UIAxes2_4, 'Z')
            app.UIAxes2_4.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_4.XTickLabel = '';
            app.CSECTIONTab = uitab(app.TabGroup);
            app.CSECTIONTab.Title = 'C SECTION ';
            app.CalculateButton_5 = uibutton(app.CSECTIONTab, 'push');
            app.CalculateButton_5.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_5Pushed, true);
            app.CalculateButton_5.Text = 'Calculate';
            app.Areaofcrosssectionm2EditField_5Label = uilabel(app.CSECTIONTab);
            app.Areaofcrosssectionm2EditField_5Label.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditField_5Label = uilabel(app.CSECTIONTab);
            app.AreaMomentofInertiam4EditField_5Label.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.StiffnessNmEditField_4Label = uilabel(app.CSECTIONTab);
            app.StiffnessNmEditField_4Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_4 = uieditfield(app.CSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_5Label = uilabel(app.CSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_5Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_5Label = uilabel(app.CSECTIONTab);
            app.NaturalfrequencyHzEditField_5Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_5Label = uilabel(app.CSECTIONTab);
            app.PeriodofOscillationsEditField_5Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_5Label = uilabel(app.CSECTIONTab);
            app.CriticalDampingNsmEditField_5Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_5Label = uilabel(app.CSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_5Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_5Label = uilabel(app.CSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_5Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_7Label = uilabel(app.CSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_7Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_7 = uieditfield(app.CSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_7Label = uilabel(app.CSECTIONTab);
            app.LogarithmicDecrementEditField_7Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_7 = uieditfield(app.CSECTIONTab, 'numeric');
            app.QualityFactorEditField_7Label = uilabel(app.CSECTIONTab);
            app.QualityFactorEditField_7Label.Text = 'Quality Factor';
            app.QualityFactorEditField_7 = uieditfield(app.CSECTIONTab, 'numeric');
            app.DropDown_14 = uidropdown(app.CSECTIONTab);
            app.DropDown_14.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_14.Value = 'cm';
            app.DropDown_15 = uidropdown(app.CSECTIONTab);
            app.DropDown_15.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_15.Value = 'mm';
            app.DropDown_16 = uidropdown(app.CSECTIONTab);
            app.DropDown_16.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_16.Value = 'cm';
            app.DropDown_17 = uidropdown(app.CSECTIONTab);
            app.DropDown_17.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_17.Value = 'mm';
            app.HeightEditField_4Label = uilabel(app.CSECTIONTab);
            app.HeightEditField_4Label.Text = 'Height';
            app.HeightEditField_4 = uieditfield(app.CSECTIONTab, 'numeric');
            app.HeightEditField_4.Limits = [0 Inf];
            app.HeightEditField_4.Value = 1;
            app.t1EditField_3Label = uilabel(app.CSECTIONTab);
            app.t1EditField_3Label.Text = 't1';
            app.t1EditField_3 = uieditfield(app.CSECTIONTab, 'numeric');
            app.t1EditField_3.Limits = [0 Inf];
            app.t1EditField_3.Value = 1;
            app.BreadthEditField_5Label = uilabel(app.CSECTIONTab);
            app.BreadthEditField_5Label.Text = 'Breadth';
            app.BreadthEditField_5 = uieditfield(app.CSECTIONTab, 'numeric');
            app.BreadthEditField_5.Limits = [0 Inf];
            app.BreadthEditField_5.Value = 1;
            app.t2EditField_3Label = uilabel(app.CSECTIONTab);
            app.t2EditField_3Label.Text = 't2';
            app.t2EditField_3 = uieditfield(app.CSECTIONTab, 'numeric');
            app.t2EditField_3.Limits = [0 Inf];
            app.t2EditField_3.Value = 1;
            app.Image5 = uiimage(app.CSECTIONTab);
            app.Image5.ImageSource = 'c section.png';
            app.UIAxes_9 = uiaxes(app.CSECTIONTab);
            title(app.UIAxes_9, 'Amplitude (m) vs Time (s) Graph')
            xlabel(app.UIAxes_9, 'Time (s)')
            ylabel(app.UIAxes_9, 'Amplitude (m) ')
            zlabel(app.UIAxes_9, 'Z')
            app.UIAxes2_5 = uiaxes(app.CSECTIONTab);
            title(app.UIAxes2_5, {'Fixed Fixed beam free vibration'; ''})
            xlabel(app.UIAxes2_5, {''; ''})
            ylabel(app.UIAxes2_5, 'Displacement (m)')
            zlabel(app.UIAxes2_5, 'Z')
            app.UIAxes2_5.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_5.XTickLabel = '';
            app.LSECTIONTab = uitab(app.TabGroup);
            app.LSECTIONTab.Title = 'L SECTION';
            app.Areaofcrosssectionm2EditField_6Label = uilabel(app.LSECTIONTab);
            app.Areaofcrosssectionm2EditField_6Label.Text = 'Area of cross section (m^2)';
            app.Areaofcrosssectionm2EditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.AreaMomentofInertiam4EditField_6Label = uilabel(app.LSECTIONTab);
            app.AreaMomentofInertiam4EditField_6Label.Text = 'Area Moment of Inertia (m^4)';
            app.AreaMomentofInertiam4EditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.StiffnessNmEditField_3Label = uilabel(app.LSECTIONTab);
            app.StiffnessNmEditField_3Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_3 = uieditfield(app.LSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_6Label = uilabel(app.LSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_6Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_6Label = uilabel(app.LSECTIONTab);
            app.NaturalfrequencyHzEditField_6Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_6Label = uilabel(app.LSECTIONTab);
            app.PeriodofOscillationsEditField_6Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_6Label = uilabel(app.LSECTIONTab);
            app.CriticalDampingNsmEditField_6Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_6Label = uilabel(app.LSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_6Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_6Label = uilabel(app.LSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_6Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_8Label = uilabel(app.LSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_8Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_8 = uieditfield(app.LSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_8Label = uilabel(app.LSECTIONTab);
            app.LogarithmicDecrementEditField_8Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_8 = uieditfield(app.LSECTIONTab, 'numeric');
            app.QualityFactorEditField_8Label = uilabel(app.LSECTIONTab);
            app.QualityFactorEditField_8Label.Text = 'Quality Factor';
            app.QualityFactorEditField_8 = uieditfield(app.LSECTIONTab, 'numeric');
            app.CalculateButton_6 = uibutton(app.LSECTIONTab, 'push');
            app.CalculateButton_6.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_6Pushed, true);
            app.CalculateButton_6.Text = 'Calculate';
            app.DropDown_18 = uidropdown(app.LSECTIONTab);
            app.DropDown_18.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_18.Value = 'cm';
            app.DropDown_19 = uidropdown(app.LSECTIONTab);
            app.DropDown_19.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_19.Value = 'mm';
            app.DropDown_20 = uidropdown(app.LSECTIONTab);
            app.DropDown_20.Items = {'cm', 'm', 'mm', 'ft'};
            app.DropDown_20.Value = 'cm';
            app.DropDown_21 = uidropdown(app.LSECTIONTab);
            app.DropDown_21.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown_21.Value = 'mm';
            app.HeightEditField_5Label = uilabel(app.LSECTIONTab);
            app.HeightEditField_5Label.Text = 'Height';
            app.HeightEditField_5 = uieditfield(app.LSECTIONTab, 'numeric');
            app.HeightEditField_5.Limits = [0 Inf];
            app.HeightEditField_5.Value = 1;
            app.t1EditField_4Label = uilabel(app.LSECTIONTab);
            app.t1EditField_4Label.Text = 't1';
            app.t1EditField_4 = uieditfield(app.LSECTIONTab, 'numeric');
            app.t1EditField_4.Limits = [0 Inf];
            app.t1EditField_4.Value = 1;
            app.BreadthEditField_6Label = uilabel(app.LSECTIONTab);
            app.BreadthEditField_6Label.Text = 'Breadth';
            app.BreadthEditField_6 = uieditfield(app.LSECTIONTab, 'numeric');
            app.BreadthEditField_6.Limits = [0 Inf];
            app.BreadthEditField_6.Value = 1;
            app.t2EditField_4Label = uilabel(app.LSECTIONTab);
            app.t2EditField_4Label.Text = 't2';
            app.t2EditField_4 = uieditfield(app.LSECTIONTab, 'numeric');
            app.t2EditField_4.Limits = [0 Inf];
            app.t2EditField_4.Value = 1;
            app.Image6 = uiimage(app.LSECTIONTab);
            app.Image6.ImageSource = 'l section.png';
            app.UIAxes_11 = uiaxes(app.LSECTIONTab);
            title(app.UIAxes_11, 'Amplitude (m) vs Time (s) Graph')
            xlabel(app.UIAxes_11, 'Time(s)')
            ylabel(app.UIAxes_11, 'Amplitude (m) ')
            zlabel(app.UIAxes_11, 'Z')
            app.UIAxes2_6 = uiaxes(app.LSECTIONTab);
            title(app.UIAxes2_6, {'Fixed Fixed beam free vibration'; ''})
            xlabel(app.UIAxes2_6, {''; ''})
            ylabel(app.UIAxes2_6, 'Displacement (m)')
            zlabel(app.UIAxes2_6, 'Z')
            app.UIAxes2_6.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_6.XTickLabel = '';
            app.OTHERSECTIONTab = uitab(app.TabGroup);
            app.OTHERSECTIONTab.Title = 'OTHER SECTION';
            app.CalculateButton_7 = uibutton(app.OTHERSECTIONTab, 'push');
            app.CalculateButton_7.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_7Pushed, true);
            app.CalculateButton_7.Text = 'Calculate';
            app.AreaofcrosssectionEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.AreaofcrosssectionEditField_7Label.Text = 'Area of cross section';
            app.AreaofcrosssectionEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.AreaofcrosssectionEditField_7.Limits = [0 Inf];
            app.AreaofcrosssectionEditField_7.Value = 10;
            app.AreaMomentofInertiaEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.AreaMomentofInertiaEditField_7Label.Text = 'Area Moment of Inertia';
            app.AreaMomentofInertiaEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.AreaMomentofInertiaEditField_7.Limits = [0 Inf];
            app.AreaMomentofInertiaEditField_7.Value = 1;
            app.StiffnessNmEditField_2Label = uilabel(app.OTHERSECTIONTab);
            app.StiffnessNmEditField_2Label.Text = 'Stiffness (N/m)';
            app.StiffnessNmEditField_2 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.CircularNaturalfrequencyradsEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.CircularNaturalfrequencyradsEditField_7Label.Text = 'Circular Natural frequency (rad/s)';
            app.CircularNaturalfrequencyradsEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.NaturalfrequencyHzEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.NaturalfrequencyHzEditField_7Label.Text = 'Natural frequency (Hz)';
            app.NaturalfrequencyHzEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.PeriodofOscillationsEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.PeriodofOscillationsEditField_7Label.Text = 'Period of Oscillation (s)';
            app.PeriodofOscillationsEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.CriticalDampingNsmEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.CriticalDampingNsmEditField_7Label.Text = 'Critical Damping (Ns/m)';
            app.CriticalDampingNsmEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.ViscousdampingcoefficientNsmEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.ViscousdampingcoefficientNsmEditField_7Label.Text = 'Viscous damping coefficient (Ns/m)';
            app.ViscousdampingcoefficientNsmEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.DampedNaturalFrequencyHzEditField_9Label = uilabel(app.OTHERSECTIONTab);
            app.DampedNaturalFrequencyHzEditField_9Label.Text = 'Damped Natural Frequency (Hz)';
            app.DampedNaturalFrequencyHzEditField_9 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.LogarithmicDecrementEditField_9Label = uilabel(app.OTHERSECTIONTab);
            app.LogarithmicDecrementEditField_9Label.Text = 'Logarithmic Decrement';
            app.LogarithmicDecrementEditField_9 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.QualityFactorEditField_9Label = uilabel(app.OTHERSECTIONTab);
            app.QualityFactorEditField_9Label.Text = 'Quality Factor';
            app.QualityFactorEditField_9 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.DampedNaturalAngularfrequencyradsEditField_7Label = uilabel(app.OTHERSECTIONTab);
            app.DampedNaturalAngularfrequencyradsEditField_7Label.Text = 'Damped Natural Angular frequency (rad/s)';
            app.DampedNaturalAngularfrequencyradsEditField_7 = uieditfield(app.OTHERSECTIONTab, 'numeric');
            app.DropDown_22 = uidropdown(app.OTHERSECTIONTab);
            app.DropDown_22.Items = {'m^2', 'mm^2', 'cm^2'};
            app.DropDown_22.Value = 'mm^2';
            app.DropDown_23 = uidropdown(app.OTHERSECTIONTab);
            app.DropDown_23.Items = {'m^4', 'mm^4', 'cm^4'};
            app.DropDown_23.Value = 'mm^4';
            app.UIAxes_13 = uiaxes(app.OTHERSECTIONTab);
            title(app.UIAxes_13, 'Amplitude (m) vs Time (s) Graph')
            xlabel(app.UIAxes_13, 'Time (s)')
            ylabel(app.UIAxes_13, 'Amplitude (m) ')
            zlabel(app.UIAxes_13, 'Z')
            app.UIAxes2_7 = uiaxes(app.OTHERSECTIONTab);
            title(app.UIAxes2_7, {'Fixed Fixed beam free vibration'; ''})
            xlabel(app.UIAxes2_7, {''; ''})
            ylabel(app.UIAxes2_7, 'Displacement (m)')
            zlabel(app.UIAxes2_7, 'Z')
            app.UIAxes2_7.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes2_7.XTickLabel = '';
            app.FORMULAUSEDTab = uitab(app.TabGroup);
            app.FORMULAUSEDTab.Title = 'FORMULA USED';
            app.Image9 = uiimage(app.FORMULAUSEDTab);
            app.Image9.ImageSource = 'formula.PNG';
            app.Image10 = uiimage(app.FORMULAUSEDTab);
            app.Image10.ImageSource = 'log que.PNG';
            app.Image11 = uiimage(app.FORMULAUSEDTab);
            app.Image11.ImageSource = 'log ans.PNG';
            app.MaterialLabel = uilabel(app.UIFigure);
            app.MaterialLabel.Text = '  Material ';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'Aluminium', 'Bronze'};
            app.MaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @MaterialDropDownValueChanged2, true);
            app.MaterialDropDown.Value = 'Bronze';
            app.LengthLLabel = uilabel(app.UIFigure);
            app.LengthLLabel.Text = 'Length(L) ';
            app.LengthLEditField = uieditfield(app.UIFigure, 'numeric');
            app.LengthLEditField.Limits = [0 Inf];
            app.LengthLEditField.Value = 1;
            app.DampingRatioLabel = uilabel(app.UIFigure);
            app.DampingRatioLabel.Text = ' Damping Ratio ';
            app.DampingRatioEditField = uieditfield(app.UIFigure, 'numeric');
            app.DampingRatioEditField.Limits = [0 100];
            app.DampingRatioEditField.ValueChangedFcn = createCallbackFcn(app, @DampingRatioEditFieldValueChanged, true);
            app.DampingRatioEditField.Value = 0.1;
            app.CentralMassLabel = uilabel(app.UIFigure);
            app.CentralMassLabel.Text = 'Central Mass ';
            app.CentralMassEditField = uieditfield(app.UIFigure, 'numeric');
            app.CentralMassEditField.Limits = [0 Inf];
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'m', 'mm', 'ft', 'cm'};
            app.DropDown.Value = 'm';
            app.DropDown_6 = uidropdown(app.UIFigure);
            app.DropDown_6.Items = {'kg', 'gm', 'lb', ''};
            app.DropDown_6.Value = 'kg';
            app.InitialdisplacementmLabel = uilabel(app.UIFigure);
            app.InitialdisplacementmLabel.Text = 'Initial displacement (m) ';
            app.InitialdisplacementmEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialdisplacementmEditField.Limits = [0 Inf];
            app.InitialdisplacementmEditField.Value = 0.001;
            app.InitialvelocitymsLabel = uilabel(app.UIFigure);
            app.InitialvelocitymsLabel.Text = 'Initial velocity (m/s) ';
            app.InitialvelocitymsEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialvelocitymsEditField.Limits = [0 Inf];
            app.InitialvelocitymsEditField.Value = 1;
            app.FIXEDFIXEDBEAMCALCULATORLabel = uilabel(app.UIFigure);
            app.FIXEDFIXEDBEAMCALCULATORLabel.Text = 'FIXED FIXED BEAM CALCULATOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = groupM2_Question7_CalculatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end